classdef DL_midi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DLmidicontrollerUIFigure  matlab.ui.Figure
        ADSRTimePanel             matlab.ui.container.Panel
        AttackKnobLabel           matlab.ui.control.Label
        AttackKnob                matlab.ui.control.Knob
        DecayKnobLabel            matlab.ui.control.Label
        DecayKnob                 matlab.ui.control.Knob
        SustainKnobLabel          matlab.ui.control.Label
        SustainKnob               matlab.ui.control.Knob
        ReleaseKnobLabel          matlab.ui.control.Label
        ReleaseKnob               matlab.ui.control.Knob
        SustainlevelKnobLabel     matlab.ui.control.Label
        SustainlevelKnob          matlab.ui.control.Knob
        UIAxes                    matlab.ui.control.UIAxes
        ADSRFrequencyPanel        matlab.ui.container.Panel
        AttackKnob_2Label         matlab.ui.control.Label
        AttackKnob_Freq           matlab.ui.control.Knob
        DecayKnob_2Label          matlab.ui.control.Label
        DecayKnob_Freq            matlab.ui.control.Knob
        SustainKnob_2Label        matlab.ui.control.Label
        SustainKnob_Freq          matlab.ui.control.Knob
        ReleaseKnob_2Label        matlab.ui.control.Label
        ReleaseKnob_Freq          matlab.ui.control.Knob
        SustainlevelKnob_2Label   matlab.ui.control.Label
        SustainlevelKnob_Freq     matlab.ui.control.Knob
        ModulationModesKnobLabel  matlab.ui.control.Label
        ModulationModesKnob       matlab.ui.control.DiscreteKnob
        UIAxes_Freq               matlab.ui.control.UIAxes
        TimeSpinnerLabel          matlab.ui.control.Label
        TimeSpinner               matlab.ui.control.Spinner
        C3                        matlab.ui.control.Button
        D3                        matlab.ui.control.Button
        E3                        matlab.ui.control.Button
        F3                        matlab.ui.control.Button
        G3                        matlab.ui.control.Button
        A3                        matlab.ui.control.Button
        B3                        matlab.ui.control.Button
        C4                        matlab.ui.control.Button
        D4                        matlab.ui.control.Button
        E4                        matlab.ui.control.Button
        F4                        matlab.ui.control.Button
        G4                        matlab.ui.control.Button
        A4                        matlab.ui.control.Button
        B4                        matlab.ui.control.Button
        Cs3                       matlab.ui.control.Button
        Ds3                       matlab.ui.control.Button
        Fs3                       matlab.ui.control.Button
        Gs3                       matlab.ui.control.Button
        As3                       matlab.ui.control.Button
        Cs4                       matlab.ui.control.Button
        Ds4                       matlab.ui.control.Button
        Fs4                       matlab.ui.control.Button
        Gs4                       matlab.ui.control.Button
        As4                       matlab.ui.control.Button
        DefualtButton             matlab.ui.control.Button
        C5                        matlab.ui.control.Button
        D5                        matlab.ui.control.Button
        E5                        matlab.ui.control.Button
        F5                        matlab.ui.control.Button
        G5                        matlab.ui.control.Button
        A5                        matlab.ui.control.Button
        B5                        matlab.ui.control.Button
        Cs5                       matlab.ui.control.Button
        Ds5                       matlab.ui.control.Button
        Fs5                       matlab.ui.control.Button
        Gs5                       matlab.ui.control.Button
        As5                       matlab.ui.control.Button
        VolumeSliderLabel         matlab.ui.control.Label
        VolumeSlider              matlab.ui.control.Slider
        SaveLastSignalButton      matlab.ui.control.Button
        NoteSelection             matlab.ui.container.Panel
        PlaynoteButton            matlab.ui.control.Button
        NoteListBox               matlab.ui.control.ListBox
        Lamp                      matlab.ui.control.Lamp
        Image                     matlab.ui.control.Image
        UIAxes_GeneratedSignal    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Fs=44100 
        TimeSpan;
        Base_pianoFreq=440;
        
        note_keys={'C3', 'Cs3', 'D3', 'Ds3', 'E3', 'F3', 'Fs3', 'G3', 'Gs3', 'A3', 'As3', 'B3'...
            'C4', 'Cs4', 'D4', 'Ds4', 'E4', 'F4', 'Fs4', 'G4', 'Gs4', 'A4', 'As4', 'B4'...
            'C5', 'Cs5', 'D5', 'Ds5', 'E5', 'F5', 'Fs5', 'G5', 'Gs5', 'A5', 'As5', 'B5'};% piano keys
        
        keyboard_keys={'q','w','e','r','t','y','u','a','s','d','f','g'...
            'h','j','z','x','c','v','b','n','m'};%keyboard keys
        keysTo_Notes
        note_key_num=28:1:63; % paino key freq identifier
        freq_map
        ADSR_env_Time
        ADSR_env_Freq
        
        ADSR_props={'Attack','Decay','Sustain','Release','Sustain_level'}
        ADSR_Time_props
        ADSR_Freq_props
        Volume
        SelectedNote_Freq_box
        LastSignal
        
    end

    
    methods (Access = private)
        % creating list for the piano manual note slection panel
        function setNotes_list(app)
            freq_list=ones(1,88);
            for n=1:88
                noteFreq=app.Base_pianoFreq*(2^(1/12))^(n-49);
                freq_list(n)=round(noteFreq,2);
            end
            freq_list=string(freq_list);
            app.NoteListBox.Items=freq_list;
        end
        % keyboard assignment to piano key
        function assign_Notes_to_keys(app)
            note_key=1;
            M=containers.Map(app.keyboard_keys,app.keyboard_keys);
            for n=1:length(app.keyboard_keys)
             TF=contains(app.note_keys(note_key),'s');
                if(TF==1)
                    note_key=note_key+1;
                    M(char(app.keyboard_keys(n)))=char(app.note_keys(note_key));
                    note_key=note_key+1;
                else
                    M(char(app.keyboard_keys(n)))=char(app.note_keys(note_key));
                    note_key=note_key+1;
                end
            end
            app.keysTo_Notes=M;
        end
        %setting default envelope values
        function setDef_ADSR_TimeEnv(app)
 
            app.AttackKnob.Value=20;
            app.DecayKnob.Value=70;
            app.SustainKnob.Value=80;
            app.SustainlevelKnob.Value=50;
            app.ReleaseKnob.Value=100;
            app.DecayKnob.Limits=[20,100];
            app.SustainKnob.Limits=[70,100];
            app.ReleaseKnob.Limits=[80,100];
            
            app.ADSR_Time_props('Attack')=0.2;
            app.ADSR_Time_props('Decay')=0.7;
            app.ADSR_Time_props('Sustain')=0.8;
            app.ADSR_Time_props('Release')=1;
            app.ADSR_Time_props('Sustain_level')=50;

            stop=app.AttackKnob.Value;
            A=linspace(0,1,0.01*stop*app.Fs);
            
            sustain_val=app.ADSR_Time_props('Sustain_level')*0.01;
            diff_decay_and_attack=app.DecayKnob.Value- app.AttackKnob.Value;
            D=linspace(1,sustain_val,(diff_decay_and_attack)*0.01*app.Fs);
           
            diff_sustain_and_decay=app.SustainKnob.Value-app.DecayKnob.Value;
            S=linspace(sustain_val,sustain_val,diff_sustain_and_decay*0.01*app.Fs);
            
            diff_release_and_sustain=1-app.SustainKnob.Value*0.01;
            R=linspace(sustain_val,0,diff_release_and_sustain*app.Fs + 2);
            a=[A D S R];
            app.ADSR_env_Time=a;
            app.UIAxes.XLim=[0,1];
            plot(app.UIAxes,app.TimeSpan,a)
            
        end
        
        function setDef_ADSR_FreqEnv(app) 
            %setting default envelopes
            app.AttackKnob_Freq.Value=20;
            app.DecayKnob_Freq.Limits=[20,100];
            app.DecayKnob_Freq.Value=70;
            app.SustainKnob_Freq.Limits=[70,100];
            app.SustainKnob_Freq.Value=80;
            app.SustainlevelKnob_Freq.Value=50;
            app.ReleaseKnob_Freq.Limits=[80,100];
            app.ReleaseKnob_Freq.Value=100;
            sustain_val=50*0.01;
             
            update_Time_knobs(app,1)
            app.ADSR_Freq_props('Attack')=0.2;
            app.ADSR_Freq_props('Decay')=0.7;
            app.ADSR_Freq_props('Sustain')=0.8;
            app.ADSR_Freq_props('Release')=1;
            app.ADSR_Freq_props('Sustain_level')=50;
            
            stop=app.AttackKnob_Freq.Value;
            
            A=linspace(0,1,0.01*stop*app.Fs);
            
            
            diff_decay_and_attack=app.DecayKnob_Freq.Value- app.AttackKnob_Freq.Value;
            D=linspace(1,sustain_val,(diff_decay_and_attack)*0.01*app.Fs);
            
            diff_sustain_and_decay=app.SustainKnob_Freq.Value-app.DecayKnob_Freq.Value;
            S=linspace(sustain_val,sustain_val,diff_sustain_and_decay*0.01*app.Fs);
            
            diff_release_and_sustain=1-app.SustainKnob_Freq.Value*0.01;
            R=linspace(sustain_val,0,diff_release_and_sustain*app.Fs + 2);
            a=[A D S R];
            app.ADSR_env_Freq=a;
            app.UIAxes_Freq.XLim=[0,1];
            plot(app.UIAxes_Freq,app.TimeSpan,a)
        end
        
        function freq= key_to_noteFreq(app,note)
            freq= (app.Base_pianoFreq*(2^(1/12))^(app.freq_map(note)-49));
        end
        
        function playKey(app,noteFreq)
            
            freq_mod=[];
            switch(app.ModulationModesKnob.Value)%frequncy modulation options
                %Mod 1- cummaltive sum iver sinus
                case 'Mod1'
                    freq_mod = sin(2*pi*cumsum(noteFreq*app.ADSR_env_Freq)/app.Fs);
                %Mod 2 - regular sinus multiplication
                case 'Mod2'
                    note= sin(2*pi*noteFreq*app.TimeSpan);
                    x = zeros(size(note));
                    x(1:length(app.ADSR_env_Freq)) = app.ADSR_env_Freq;
                    freq_mod=note.*x;
            end

            final_sound=(freq_mod.*app.ADSR_env_Time)*app.Volume;%amplitude moduation
            sound(final_sound,app.Fs);
            app.LastSignal=final_sound;
            app.UIAxes_GeneratedSignal.YLim=[-1,1];
            plot(app.UIAxes_GeneratedSignal,app.TimeSpan,final_sound)
            
        end
        %changing one knob leads changing the others limit values- this
        %function handles that for Frequncy panel
        function update_Time_knobs(app,knob_id)
            switch(knob_id)
                case 1
                    
                    lim_dec=app.DecayKnob.Value;
                    lim_sus=app.SustainKnob.Value;
                    lim=app.ADSR_Time_props('Attack')*100;
                    app.DecayKnob.Limits=[lim,100];
                    if lim_dec > lim
                         app.SustainKnob.Limits=[lim_dec,100];
                         app.ReleaseKnob.Limits=[lim_sus,100];
                    else
                        app.SustainKnob.Limits=[lim,100];
                        app.ReleaseKnob.Limits=[lim,100];
                    end
                    app.ADSR_Time_props('Sustain')=app.SustainKnob.Value/100;
                    app.ADSR_Time_props('Decay')=app.DecayKnob.Value/100;
                    app.ADSR_Time_props('Release')=app.ReleaseKnob.Value/100;
                    
                case 2
                    lim=app.ADSR_Time_props('Decay')*100;
                    lim_sus=app.SustainKnob.Value;
                    if lim > lim_sus
                        app.SustainKnob.Limits=[lim,100];
                        app.ReleaseKnob.Limits=[lim,100];
                    else
                        app.SustainKnob.Limits=[lim,100];
                        app.ReleaseKnob.Limits=[lim_sus,100];
                    end
                    app.ADSR_Time_props('Sustain')=app.SustainKnob.Value/100;
                    app.ADSR_Time_props('Release')=app.ReleaseKnob.Value/100;
                case 3
                    lim_sus=app.SustainKnob.Value;
                    app.ReleaseKnob.Limits=[lim_sus,100];
            end
        end
        
         %changing one knob leads changing the others limit values- this
         %function handles that for Frequncy panel
        function update_Freq_knobs(app,knob_id)
            switch(knob_id)
                case 1
                    
                    lim_dec=app.DecayKnob_Freq.Value;
                    lim_sus=app.SustainKnob_Freq.Value;
                    lim=app.ADSR_Freq_props('Attack')*100;
                    app.DecayKnob_Freq.Limits=[lim,100];
                    if lim_dec > lim
                         app.SustainKnob_Freq.Limits=[lim_dec,100];
                         app.ReleaseKnob_Freq.Limits=[lim_sus,100];
                    else
                        app.SustainKnob_Freq.Limits=[lim,100];
                        app.ReleaseKnob_Freq.Limits=[lim,100];
                    end
                    app.ADSR_Freq_props('Sustain')=app.SustainKnob_Freq.Value/100;
                    app.ADSR_Freq_props('Decay')=app.DecayKnob_Freq.Value/100;
                    app.ADSR_Freq_props('Release')=app.ReleaseKnob_Freq.Value/100;
                    
                case 2
                    lim=app.ADSR_Freq_props('Decay')*100;
                    lim_sus=app.SustainKnob_Freq.Value;
                    if lim > lim_sus
                        app.SustainKnob_Freq.Limits=[lim,100];
                        app.ReleaseKnob_Freq.Limits=[lim,100];
                    else
                        app.SustainKnob_Freq.Limits=[lim,100];
                        app.ReleaseKnob_Freq.Limits=[lim_sus,100];
                    end
                    app.ADSR_Freq_props('Sustain')=app.SustainKnob_Freq.Value/100;
                    app.ADSR_Freq_props('Release')=app.ReleaseKnob_Freq.Value/100;
                case 3
                    lim_sus=app.SustainKnob_Freq.Value;
                    app.ReleaseKnob_Freq.Limits=[lim_sus,100];
            end
        end
        
        %func that creates/updates both envelopes and updates relevant
        %params
            
        function create_ADSR_env(app,env_id)
            if env_id=='TIME'
                samples_num=length(app.TimeSpan);
                stop=app.ADSR_Time_props('Attack');
                A=linspace(0,1,stop*samples_num);
            
                sus_val=app.ADSR_Time_props('Sustain_level');
                if sus_val>1
                    sus_val=sus_val/100;
                end
                diff_decay_and_attack=app.ADSR_Time_props('Decay')- app.ADSR_Time_props('Attack');
                D=linspace(1,sus_val,(diff_decay_and_attack)*samples_num);
            
                diff_sustain_and_decay=app.ADSR_Time_props('Sustain')-app.ADSR_Time_props('Decay');
                S=linspace(sus_val,sus_val,diff_sustain_and_decay*samples_num);
            
                diff_release_and_sustain=1*app.ADSR_Time_props('Release')-app.ADSR_Time_props('Sustain');
                R=linspace(sus_val,0,diff_release_and_sustain*samples_num );
                adsr=[A D S R];
            %padding
                if length(adsr)<samples_num
                    adsr=[adsr zeros(1,samples_num-length(adsr))];
                end
                app.ADSR_env_Time=adsr;
                plot(app.UIAxes,app.TimeSpan,adsr);
                app.UIAxes.XLim=[0,app.TimeSpinner.Value];
            else
                samples_num=length(app.TimeSpan);
                stop=app.ADSR_Freq_props('Attack');
                A=linspace(0,1,stop*samples_num);
            
                sus_val=app.ADSR_Freq_props('Sustain_level');
                if sus_val>1
                    sus_val=sus_val/100;
                end
                diff_decay_and_attack=app.ADSR_Freq_props('Decay')- app.ADSR_Freq_props('Attack');
                D=linspace(1,sus_val,(diff_decay_and_attack)*samples_num);
            
                diff_sustain_and_decay=app.ADSR_Freq_props('Sustain')-app.ADSR_Freq_props('Decay');
                S=linspace(sus_val,sus_val,diff_sustain_and_decay*samples_num);
            
                diff_release_and_sustain=1*app.ADSR_Freq_props('Release')-app.ADSR_Freq_props('Sustain');
                R=linspace(sus_val,0,diff_release_and_sustain*samples_num );
                adsr=[A D S R];
            %padding
                if length(adsr)<samples_num
                    adsr=[adsr zeros(1,samples_num-length(adsr))];
                else
                    adsr=adsr;%for debugging purposes 
                end
                app.ADSR_env_Freq=adsr;
                
                plot(app.UIAxes_Freq,app.TimeSpan,adsr);
                app.UIAxes_Freq.XLim=[0,app.TimeSpinner.Value];
            end
        
            
        end
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %start up func-setting default params and variables
            app.Volume=1;
            app.TimeSpan=0:1/app.Fs:1;
            app.ADSR_Time_props=containers.Map(app.ADSR_props,{0.0,0.0,0.0,0.0,0.0});
            app.ADSR_Freq_props=containers.Map(app.ADSR_props,{0.0,0.0,0.0,0.0,0.0});
            app.freq_map=containers.Map(app.note_keys,app.note_key_num);
            app.TimeSpan = 0 : 1/app.Fs : app.TimeSpinner.Value;
            setDef_ADSR_TimeEnv(app);
            setDef_ADSR_FreqEnv(app);
            setNotes_list(app);
            assign_Notes_to_keys(app);
        end

        % Button pushed function: A3, A4, A5, As3, As4, As5, B3, 
        % B4, B5, C3, C4, C5, Cs3, Cs4, Cs5, D3, D4, D5, Ds3, Ds4, 
        % Ds5, E3, E4, E5, F3, F4, F5, Fs3, Fs4, Fs5, G3, G4, G5, 
        % Gs3, Gs4, Gs5
        function C0ButtonPushed(app, event)
            key=event.Source.Tag;
            freq=key_to_noteFreq(app,key);
            playKey(app,freq);
        end

        % Value changed function: VolumeSlider
        function VolumeSliderValueChanged(app, event)
            value = app.VolumeSlider.Value;
            app.Volume=value/100;
        end

        % Value changed function: TimeSpinner
        function TimeSpinnerValueChanged(app, event)
            value = app.TimeSpinner.Value;
            app.TimeSpan=0:1/app.Fs:value;
            create_ADSR_env(app,'TIME');
            create_ADSR_env(app,'FREQ');
        end

        % Value changing function: AttackKnob_Freq
        function AttackKnob_FreqValueChanging(app, event)
            changingValue = event.Value;
            app.ADSR_Freq_props('Attack')=changingValue/100;
            update_Freq_knobs(app,1)
            create_ADSR_env(app,'FREQ')
        end

        % Value changing function: DecayKnob_Freq
        function DecayKnob_FreqValueChanging(app, event)
            changingValue = event.Value;
            app.ADSR_Freq_props('Decay')=changingValue/100;
            update_Freq_knobs(app,2)
            create_ADSR_env(app,'FREQ')
        end

        % Value changing function: SustainKnob_Freq
        function SustainKnob_FreqValueChanging(app, event)
            changingValue = event.Value;
            app.ADSR_Freq_props('Sustain')=changingValue/100;
            update_Freq_knobs(app,3)
            create_ADSR_env(app,'FREQ')
        end

        % Value changing function: ReleaseKnob_Freq
        function ReleaseKnob_FreqValueChanging(app, event)
            changingValue = event.Value;
            app.ADSR_Freq_props('Release')=changingValue/100;
            create_ADSR_env(app,'FREQ')
        end

        % Button pushed function: DefualtButton
        function DefualtButtonPushed(app, event)
            app.VolumeSlider.Value=100;
            app.TimeSpinner.Value=1;
            app.startupFcn();
        end

        % Button pushed function: SaveLastSignalButton
        function SaveLastSignalButtonPushed(app, event)
           %button save last signal
            if ~isempty(app.LastSignal)
                [file, folder] = uiputfile('exmple1.wav');
                if ~isequal(file, 0)  % User did not press Cancel:
                    audiowrite(fullfile(folder, file), app.LastSignal, app.Fs);
                end
            end
        end

        % Value changed function: NoteListBox
        function NoteListBoxValueChanged(app, event)
            value = app.NoteListBox.Value;
            app.SelectedNote_Freq_box=value;
            if str2double(value)<550
                app.Lamp.Color='g';
            else
                app.Lamp.Color='r';
            end
        end

        % Button pushed function: PlaynoteButton
        function PlaynoteButtonPushed(app, event)
            freq=app.SelectedNote_Freq_box;
            if ~isempty(freq)
                playKey(app,str2num(freq));
            end
        end

        % Value changing function: SustainlevelKnob_Freq
        function SustainlevelKnob_FreqValueChanging(app, event)
            changingValue = event.Value;
            app.ADSR_Freq_props('Sustain_level')=changingValue/100;
            create_ADSR_env(app,'FREQ')
        end

        % Value changing function: SustainlevelKnob
        function SustainlevelKnobValueChanging(app, event)
            changingValue = event.Value;
            app.ADSR_Time_props('Sustain_level')=changingValue/100;
            create_ADSR_env(app,'TIME')
        end

        % Key press function: DLmidicontrollerUIFigure
        function UIKeyPress(app, event)
            key = event.Key;
                   
            if any(strcmp(app.keyboard_keys,key))
                note= app.keysTo_Notes(key);
                freq=key_to_noteFreq(app,note);
                playKey(app,freq);
            end
        end

        % Value changing function: AttackKnob
        function AttackKnobValueChanging(app, event)
            changingValue = event.Value;
            app.ADSR_Time_props('Attack')=changingValue/100;
            update_Time_knobs(app,1)
            create_ADSR_env(app,'TIME')
        end

        % Value changing function: DecayKnob
        function DecayKnobValueChanging(app, event)
            changingValue = event.Value;
            app.ADSR_Time_props('Decay')=changingValue/100;
            update_Time_knobs(app,2)
            create_ADSR_env(app,'TIME')
        end

        % Value changing function: SustainKnob
        function SustainKnobValueChanging(app, event)
            changingValue = event.Value;
            app.ADSR_Time_props('Sustain')=changingValue/100;
            update_Time_knobs(app,3);
            create_ADSR_env(app,'TIME')
        end

        % Value changing function: ReleaseKnob
        function ReleaseKnobValueChanging(app, event)
            changingValue = event.Value;
            app.ADSR_Time_props('Release')=changingValue/100;
            create_ADSR_env(app,'TIME')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DLmidicontrollerUIFigure = uifigure('Visible', 'off');
            app.DLmidicontrollerUIFigure.Name = 'DL midi controller';
            app.DLmidicontrollerUIFigure.Icon = 'DJ3x_42210.png';
            app.DLmidicontrollerUIFigure.KeyPressFcn = createCallbackFcn(app, @UIKeyPress, true);
            app.ADSRTimePanel = uipanel(app.DLmidicontrollerUIFigure);
            app.ADSRTimePanel.Title = 'ADSR (Time)';
            app.AttackKnobLabel = uilabel(app.ADSRTimePanel);
            app.AttackKnobLabel.Text = 'Attack';
            app.AttackKnob = uiknob(app.ADSRTimePanel, 'continuous');
            app.AttackKnob.Limits = [0 98];
            app.AttackKnob.ValueChangingFcn = createCallbackFcn(app, @AttackKnobValueChanging, true);
            app.DecayKnobLabel = uilabel(app.ADSRTimePanel);
            app.DecayKnobLabel.Text = 'Decay';
            app.DecayKnob = uiknob(app.ADSRTimePanel, 'continuous');
            app.DecayKnob.ValueChangingFcn = createCallbackFcn(app, @DecayKnobValueChanging, true);
            app.SustainKnobLabel = uilabel(app.ADSRTimePanel);
            app.SustainKnobLabel.Text = 'Sustain';
            app.SustainKnob = uiknob(app.ADSRTimePanel, 'continuous');
            app.SustainKnob.ValueChangingFcn = createCallbackFcn(app, @SustainKnobValueChanging, true);
            app.SustainKnob.Tag = 'Sustain';
            app.ReleaseKnobLabel = uilabel(app.ADSRTimePanel);
            app.ReleaseKnobLabel.Text = 'Release';
            app.ReleaseKnob = uiknob(app.ADSRTimePanel, 'continuous');
            app.ReleaseKnob.ValueChangingFcn = createCallbackFcn(app, @ReleaseKnobValueChanging, true);
            app.SustainlevelKnobLabel = uilabel(app.ADSRTimePanel);
            app.SustainlevelKnobLabel.Text = 'Sustain level';
            app.SustainlevelKnob = uiknob(app.ADSRTimePanel, 'continuous');
            app.SustainlevelKnob.ValueChangingFcn = createCallbackFcn(app, @SustainlevelKnobValueChanging, true);
            app.SustainlevelKnob.Value = 50;
            app.UIAxes = uiaxes(app.ADSRTimePanel);
            title(app.UIAxes, 'Time ADSR Envelope')
            xlabel(app.UIAxes, 'Time[sec]')
            ylabel(app.UIAxes, 'Vol')
            zlabel(app.UIAxes, 'Z')
            app.ADSRFrequencyPanel = uipanel(app.DLmidicontrollerUIFigure);
            app.ADSRFrequencyPanel.Title = 'ADSR (Frequency)';
            app.AttackKnob_2Label = uilabel(app.ADSRFrequencyPanel);
            app.AttackKnob_2Label.Text = 'Attack';
            app.AttackKnob_Freq = uiknob(app.ADSRFrequencyPanel, 'continuous');
            app.AttackKnob_Freq.Limits = [0 98];
            app.AttackKnob_Freq.ValueChangingFcn = createCallbackFcn(app, @AttackKnob_FreqValueChanging, true);
            app.DecayKnob_2Label = uilabel(app.ADSRFrequencyPanel);
            app.DecayKnob_2Label.Text = 'Decay';
            app.DecayKnob_Freq = uiknob(app.ADSRFrequencyPanel, 'continuous');
            app.DecayKnob_Freq.ValueChangingFcn = createCallbackFcn(app, @DecayKnob_FreqValueChanging, true);
            app.SustainKnob_2Label = uilabel(app.ADSRFrequencyPanel);
            app.SustainKnob_2Label.Text = 'Sustain';
            app.SustainKnob_Freq = uiknob(app.ADSRFrequencyPanel, 'continuous');
            app.SustainKnob_Freq.ValueChangingFcn = createCallbackFcn(app, @SustainKnob_FreqValueChanging, true);
            app.ReleaseKnob_2Label = uilabel(app.ADSRFrequencyPanel);
            app.ReleaseKnob_2Label.Text = 'Release';
            app.ReleaseKnob_Freq = uiknob(app.ADSRFrequencyPanel, 'continuous');
            app.ReleaseKnob_Freq.ValueChangingFcn = createCallbackFcn(app, @ReleaseKnob_FreqValueChanging, true);
            app.SustainlevelKnob_2Label = uilabel(app.ADSRFrequencyPanel);
            app.SustainlevelKnob_2Label.Text = 'Sustain level';
            app.SustainlevelKnob_Freq = uiknob(app.ADSRFrequencyPanel, 'continuous');
            app.SustainlevelKnob_Freq.ValueChangingFcn = createCallbackFcn(app, @SustainlevelKnob_FreqValueChanging, true);
            app.SustainlevelKnob_Freq.Value = 50;
            app.ModulationModesKnobLabel = uilabel(app.ADSRFrequencyPanel);
            app.ModulationModesKnobLabel.Text = 'Modulation Modes';
            app.ModulationModesKnob = uiknob(app.ADSRFrequencyPanel, 'discrete');
            app.ModulationModesKnob.Items = {'Mod1', 'Mod2'};
            app.ModulationModesKnob.Tooltip = {'Mod1- cumulative sum sinusoidal'; 'Mod2- simple sinus modlutaion  '};
            app.ModulationModesKnob.Value = 'Mod1';
            app.UIAxes_Freq = uiaxes(app.ADSRFrequencyPanel);
            title(app.UIAxes_Freq, 'Frequency ADSR Envelope')
            xlabel(app.UIAxes_Freq, 'Time [sec]')
            ylabel(app.UIAxes_Freq, 'Vol')
            zlabel(app.UIAxes_Freq, 'Z')
            app.TimeSpinnerLabel = uilabel(app.DLmidicontrollerUIFigure);
            app.TimeSpinnerLabel.Text = 'Time';
            app.TimeSpinner = uispinner(app.DLmidicontrollerUIFigure);
            app.TimeSpinner.Step = 0.1;
            app.TimeSpinner.Limits = [0.5 5];
            app.TimeSpinner.ValueChangedFcn = createCallbackFcn(app, @TimeSpinnerValueChanged, true);
            app.TimeSpinner.Value = 1;
            app.C3 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.C3.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.C3.Tag = 'C3';
            app.C3.Text = 'C3';
            app.D3 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.D3.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.D3.Tag = 'D3';
            app.D3.Text = 'D3';
            app.E3 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.E3.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.E3.Tag = 'E3';
            app.E3.Text = 'E3';
            app.F3 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.F3.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.F3.Tag = 'F3';
            app.F3.Text = 'F3';
            app.G3 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.G3.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.G3.Tag = 'G3';
            app.G3.Text = 'G3';
            app.A3 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.A3.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.A3.Tag = 'A3';
            app.A3.Text = 'A3';
            app.B3 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.B3.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.B3.Tag = 'B3';
            app.B3.Text = 'B3';
            app.C4 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.C4.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.C4.Tag = 'C4';
            app.C4.Text = 'C4';
            app.D4 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.D4.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.D4.Tag = 'D4';
            app.D4.Text = 'D4';
            app.E4 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.E4.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.E4.Tag = 'E4';
            app.E4.Text = 'E4';
            app.F4 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.F4.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.F4.Tag = 'F4';
            app.F4.Text = 'F4';
            app.G4 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.G4.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.G4.Tag = 'G4';
            app.G4.Text = 'G4';
            app.A4 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.A4.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.A4.Tag = 'A4';
            app.A4.Text = 'A4';
            app.B4 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.B4.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.B4.Tag = 'B4';
            app.B4.Text = 'B4';
            app.Cs3 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.Cs3.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.Cs3.Tag = 'Cs3';
            app.Cs3.Text = 'C#3';
            app.Ds3 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.Ds3.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.Ds3.Tag = 'Ds3';
            app.Ds3.Text = 'D#3';
            app.Fs3 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.Fs3.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.Fs3.Tag = 'Fs3';
            app.Fs3.Text = 'F#3';
            app.Gs3 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.Gs3.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.Gs3.Tag = 'Gs3';
            app.Gs3.Text = 'G#3';
            app.As3 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.As3.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.As3.Tag = 'As3';
            app.As3.Text = 'A#3';
            app.Cs4 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.Cs4.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.Cs4.Tag = 'Cs4';
            app.Cs4.Text = 'C#4';
            app.Ds4 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.Ds4.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.Ds4.Tag = 'Ds4';
            app.Ds4.Text = 'D#4';
            app.Fs4 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.Fs4.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.Fs4.Tag = 'Fs4';
            app.Fs4.Text = 'F#4';
            app.Gs4 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.Gs4.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.Gs4.Tag = 'Gs4';
            app.Gs4.Text = 'G#4';
            app.As4 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.As4.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.As4.Tag = 'As4';
            app.As4.Text = 'A#4';
            app.DefualtButton = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.DefualtButton.ButtonPushedFcn = createCallbackFcn(app, @DefualtButtonPushed, true);
            app.DefualtButton.Text = 'Defualt';
            app.C5 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.C5.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.C5.Tag = 'C5';
            app.C5.Text = 'C5';
            app.D5 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.D5.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.D5.Tag = 'D5';
            app.D5.Text = 'D5';
            app.E5 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.E5.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.E5.Tag = 'E5';
            app.E5.Text = 'E5';
            app.F5 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.F5.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.F5.Tag = 'F5';
            app.F5.Text = 'F5';
            app.G5 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.G5.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.G5.Tag = 'G5';
            app.G5.Text = 'G5';
            app.A5 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.A5.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.A5.Tag = 'A5';
            app.A5.Text = 'A5';
            app.B5 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.B5.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.B5.Tag = 'B5';
            app.B5.Text = 'B5';
            app.Cs5 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.Cs5.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.Cs5.Tag = 'Cs5';
            app.Cs5.Text = 'C#5';
            app.Ds5 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.Ds5.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.Ds5.Tag = 'Ds5';
            app.Ds5.Text = 'D#5';
            app.Fs5 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.Fs5.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.Fs5.Tag = 'Fs5';
            app.Fs5.Text = 'F#5';
            app.Gs5 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.Gs5.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.Gs5.Tag = 'Gs5';
            app.Gs5.Text = 'G#5';
            app.As5 = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.As5.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.As5.Tag = 'As5';
            app.As5.Text = 'A#5';
            app.VolumeSliderLabel = uilabel(app.DLmidicontrollerUIFigure);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.DLmidicontrollerUIFigure);
            app.VolumeSlider.ValueChangedFcn = createCallbackFcn(app, @VolumeSliderValueChanged, true);
            app.VolumeSlider.Value = 100;
            app.SaveLastSignalButton = uibutton(app.DLmidicontrollerUIFigure, 'push');
            app.SaveLastSignalButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLastSignalButtonPushed, true);
            app.SaveLastSignalButton.Text = 'Save Last Signal';
            app.NoteSelection = uipanel(app.DLmidicontrollerUIFigure);
            app.NoteSelection.Tooltip = {'Manual selection of piano key freq- Be Careful with HIGH FREQUENCY notes'};
            app.NoteSelection.Title = 'Select Piano Note';
            app.PlaynoteButton = uibutton(app.NoteSelection, 'push');
            app.PlaynoteButton.ButtonPushedFcn = createCallbackFcn(app, @PlaynoteButtonPushed, true);
            app.PlaynoteButton.Text = 'Play note';
            app.NoteListBox = uilistbox(app.NoteSelection);
            app.NoteListBox.Items = {};
            app.NoteListBox.ValueChangedFcn = createCallbackFcn(app, @NoteListBoxValueChanged, true);
            app.NoteListBox.Value = {};
            app.Lamp = uilamp(app.NoteSelection);
            app.Image = uiimage(app.DLmidicontrollerUIFigure);
            app.Image.ImageSource = 'SynthLogoDLgold.png';
            app.UIAxes_GeneratedSignal = uiaxes(app.DLmidicontrollerUIFigure);
            title(app.UIAxes_GeneratedSignal, {'Generated Signal'; ''})
            zlabel(app.UIAxes_GeneratedSignal, 'Z')
            % Show the figure after all components are created
            app.DLmidicontrollerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DL_midi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DLmidicontrollerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DLmidicontrollerUIFigure)
        end
    end
end